classdef UNPIC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UNPICUIFigure                   matlab.ui.Figure
        MainPanel                       matlab.ui.container.Panel
        MainTabGroup                    matlab.ui.container.TabGroup
        ImageDataTab                    matlab.ui.container.Tab
        DataRandomImagesUIAxes          matlab.ui.control.UIAxes
        DataClassTable                  matlab.ui.control.Table
        DataRandomImagesButton          matlab.ui.control.Button
        ObstoshowLabel                  matlab.ui.control.Label
        DataNumObsToShowSpinner         matlab.ui.control.Spinner
        DataNumObsLabel                 matlab.ui.control.Label
        DataNumObsValue                 matlab.ui.control.Label
        DataSourceLabel                 matlab.ui.control.Label
        DataSourceValue                 matlab.ui.control.Label
        DataNumClassesLabel             matlab.ui.control.Label
        DataNumClassesValue             matlab.ui.control.Label
        AccuracyTab                     matlab.ui.container.Tab
        AccuracyTabGroup                matlab.ui.container.TabGroup
        ConfusionMatrixTab              matlab.ui.container.Tab
        ConfusionMatrixPanel            matlab.ui.container.Panel
        ConfusionMatrixButton           matlab.ui.control.Button
        PredictedImagesbyClassTab       matlab.ui.container.Tab
        PredictedClassImagesUIAxes      matlab.ui.control.UIAxes
        PredictedClassImagesLabel       matlab.ui.control.Label
        PredictedClassImagesValue       matlab.ui.control.Label
        TruevsPredTab                   matlab.ui.container.Tab
        TruevsPredUIAxes                matlab.ui.control.UIAxes
        TrueclassLabel                  matlab.ui.control.Label
        TruevsPredTrueClassDropDown     matlab.ui.control.DropDown
        PredictedclassLabel             matlab.ui.control.Label
        TruevsPredPredictedClassDropDown  matlab.ui.control.DropDown
        TruevsPredRandomImageButton     matlab.ui.control.Button
        TruevsPredValue                 matlab.ui.control.Label
        AccuracyTable                   matlab.ui.control.Table
        AccuracyButton                  matlab.ui.control.Button
        AccuracyLabel                   matlab.ui.control.Label
        PredictTab                      matlab.ui.container.Tab
        PredictHistUIAxes               matlab.ui.control.UIAxes
        PredictSingleImageUIAxes        matlab.ui.control.UIAxes
        PredictScoreUITable             matlab.ui.control.Table
        PredictChooseImageFileEditFieldLabel  matlab.ui.control.Label
        PredictChooseImageFileEditField  matlab.ui.control.EditField
        PredictSingleRandomImageButton  matlab.ui.control.Button
        PredictRandomImageClassDropDown  matlab.ui.control.DropDown
        RandomimagefromclassLabel_4     matlab.ui.control.Label
        PredictTabTextArea              matlab.ui.control.TextArea
        PredictImageLabel               matlab.ui.control.Label
        PredictImageValue               matlab.ui.control.Label
        PredictionExplainerTab          matlab.ui.container.Tab
        ExplainerSingleImageUIAxes      matlab.ui.control.UIAxes
        ExplainerTabGroup               matlab.ui.container.TabGroup
        OcclusionSensitivityTab         matlab.ui.container.Tab
        OcclusionSensitivitySettingsPanel  matlab.ui.container.Panel
        OcclusionSlider                 matlab.ui.control.Slider
        MasksizeSpinnerLabel            matlab.ui.control.Label
        OcclusionMasksizeSpinner        matlab.ui.control.Spinner
        StrideLabel                     matlab.ui.control.Label
        OcclusionStrideSpinner          matlab.ui.control.Spinner
        OcclusionTargetclassDropDownLabel  matlab.ui.control.Label
        OcclusionTargetclassDropDown    matlab.ui.control.DropDown
        OcclusionButton                 matlab.ui.control.Button
        OcclusionTextArea               matlab.ui.control.TextArea
        OcclusionHelpButton             matlab.ui.control.Button
        GradCAMTab                      matlab.ui.container.Tab
        GradCAMSettingsPanel            matlab.ui.container.Panel
        GradCAMButton                   matlab.ui.control.Button
        GradCAMTargetclassDropDownLabel  matlab.ui.control.Label
        GradCAMTargetclassDropDown      matlab.ui.control.DropDown
        GradCAMFeaturemapDropDownLabel  matlab.ui.control.Label
        GradCAMFeatureMapDropDown       matlab.ui.control.DropDown
        GradCAMTextArea                 matlab.ui.control.TextArea
        GradCAMHelpButton               matlab.ui.control.Button
        GradientAttributionTab          matlab.ui.container.Tab
        GradientAttributionSettingsPanel  matlab.ui.container.Panel
        GradientAttributionTargetclassDropDownLabel  matlab.ui.control.Label
        GradientAttributionTargetclassDropDown  matlab.ui.control.DropDown
        GradientAttributionButton       matlab.ui.control.Button
        GradientAttributionTextArea     matlab.ui.control.TextArea
        GradientAttributionHelpButton   matlab.ui.control.Button
        ExplainerChooseImageFileEditFieldLabel  matlab.ui.control.Label
        ExplainerChooseImageFileEditField  matlab.ui.control.EditField
        ExplainerSingleRandomImageButton  matlab.ui.control.Button
        RandomimagefromclassLabel_3     matlab.ui.control.Label
        ExplainerRandomImageClassDropDown  matlab.ui.control.DropDown
        ExplainerTabTextArea            matlab.ui.control.TextArea
        ExplainerLabel                  matlab.ui.control.Label
        ExplainerValue                  matlab.ui.control.Label
        FeaturesTab                     matlab.ui.container.Tab
        FeaturesSettingsPanel           matlab.ui.container.Panel
        ChannelVizChooseLayerDropDownLabel  matlab.ui.control.Label
        FeaturesChooseLayerDropDown     matlab.ui.control.DropDown
        ChannelVizChooseChannelsEditFieldLabel  matlab.ui.control.Label
        FeaturesChooseChannelsEditField  matlab.ui.control.EditField
        FeaturesTabGroup                matlab.ui.container.TabGroup
        ActivationsTab                  matlab.ui.container.Tab
        ActivationsUIAxes               matlab.ui.control.UIAxes
        ActivationsSingleImageUIAxes    matlab.ui.control.UIAxes
        ActivationsButton               matlab.ui.control.Button
        ActivationsTextArea             matlab.ui.control.TextArea
        ActivationsChooseImageFileEditFieldLabel  matlab.ui.control.Label
        ActivationsChooseImageFileEditField  matlab.ui.control.EditField
        ActivationsSingleRandomImageButton  matlab.ui.control.Button
        ActivationsRandomImageClassDropDown  matlab.ui.control.DropDown
        RandomimagefromclassLabel_2     matlab.ui.control.Label
        ActivationsHelpButton           matlab.ui.control.Button
        ActivationDistributionTab       matlab.ui.container.Tab
        ActivationDistributionSingleImageUIAxes  matlab.ui.control.UIAxes
        ActivationDistributionComputeButton  matlab.ui.control.Button
        ActivationDistributionPanel     matlab.ui.container.Panel
        ActivationDistributionTextArea  matlab.ui.control.TextArea
        ActivationDistributionChooseImageFileEditFieldLabel  matlab.ui.control.Label
        ActivationDistributionChooseImageFileEditField  matlab.ui.control.EditField
        ActivationDistributionSingleRandomImageButton  matlab.ui.control.Button
        ActivationDistributionRandomImageClassDropDown  matlab.ui.control.DropDown
        RandomimagefromclassLabel       matlab.ui.control.Label
        MaxActivationsTab               matlab.ui.container.Tab
        MaxActivationsPanel             matlab.ui.container.Panel
        MaxActivationsTextArea          matlab.ui.control.TextArea
        MaxActivationsImagesSettingsPanel  matlab.ui.container.Panel
        MaxActivationsImagesButton      matlab.ui.control.Button
        MaxActsImagesperchannelEditFieldLabel  matlab.ui.control.Label
        MaxActivationsImagesperchannelEditField  matlab.ui.control.EditField
        MaxActivationsHelpButton        matlab.ui.control.Button
        DeepDreamTab                    matlab.ui.container.Tab
        DeepDreamUIAxes                 matlab.ui.control.UIAxes
        DeepDreamTextArea               matlab.ui.control.TextArea
        DeepDreamSettingsPanel          matlab.ui.container.Panel
        DeepDreamComputeButton          matlab.ui.control.Button
        IterationsLabel                 matlab.ui.control.Label
        DeepDreamIterationsSpinner      matlab.ui.control.Spinner
        PyramidlevelsLabel              matlab.ui.control.Label
        DeepDreamPyrLvlsSpinner         matlab.ui.control.Spinner
        DeepDreamVerboseCheckBox        matlab.ui.control.CheckBox
        DeepDreamHelpButton             matlab.ui.control.Button
        tSNETab                         matlab.ui.container.Tab
        tSNEUIAxes                      matlab.ui.control.UIAxes
        tSNETable                       matlab.ui.control.Table
        tSNEChooseLayerDropDownLabel    matlab.ui.control.Label
        tSNEChooseLayerDropDown         matlab.ui.control.DropDown
        tSNEIncludeeverySpinnerLabel    matlab.ui.control.Label
        tSNEIncludeEverySpinner         matlab.ui.control.Spinner
        tSNEButton                      matlab.ui.control.Button
        tSNEUpdateTableButton           matlab.ui.control.Button
        tSNETextArea                    matlab.ui.control.TextArea
        tSNEHelpButton                  matlab.ui.control.Button
        tSNETipLabel                    matlab.ui.control.Label
    end

    
    events
        UserAppUpdated
    end
    
    properties (Access = private)
        DLNet
        DataModel
        CurrentPredictImage
        ClassificationCache
        TSNEPlotter
    end
    
    properties (Access = public)
        Net
        Data
    end
    
    methods (Access = public)
        
        function appUpdate(app, net, data)
            app.Net = net;
            app.Data = data;
            app.DataModel = ImageDataPropertiesModel(data);
            app.update();
        end
        
        function update(app)
            layers = cellstr({app.Net.Layers.Name});
            classes = cellstr(app.DataModel.Classes);
            
            % Data Tab
            data(:,1) = classes;
            data(:,2) = num2cell(app.DataModel.NumObservationsByClass);
            
            app.DataNumObsValue.Text = string(app.DataModel.TotalObservations);
            app.DataSourceValue.Text = string(app.DataModel.ImageDatastore.Folders);
            app.DataNumClassesValue.Text = string(length(classes));
            app.DataClassTable.Data = data;
            
            % Accuracy Tab
            app.TruevsPredTrueClassDropDown.Items = classes;
            app.TruevsPredPredictedClassDropDown.Items = classes;
            
            % Predict Tab
            app.PredictRandomImageClassDropDown.Items = classes;
            
            % Explainer Tab
            app.ExplainerRandomImageClassDropDown.Items = classes;
            app.OcclusionTargetclassDropDown.Items = classes;
            app.GradCAMTargetclassDropDown.Items = classes;
            app.GradCAMFeatureMapDropDown.Items = layers;
            % Default layer suitable for use with GoogLeNet
            app.GradCAMFeatureMapDropDown.Value = layers(end-5);
            app.GradientAttributionTargetclassDropDown.Items = classes;
            
            % Features Tab
            app.ActivationsRandomImageClassDropDown.Items = classes;
            app.ActivationDistributionRandomImageClassDropDown.Items = classes;
            app.FeaturesChooseLayerDropDown.Items = layers;
            % Default layer suitable for use with GoogLeNet
            app.FeaturesChooseLayerDropDown.Value = layers(2);
            
            % tsne Tab
            app.tSNEChooseLayerDropDown.Items = layers;
            % Default layer suitable for use with GoogLeNet
            app.tSNEChooseLayerDropDown.Value = layers(end-1);
            app.tSNETable.Data = data(:,1);
            app.tSNETable.Data(:,2) = num2cell(logical(size(data, 1)));
            app.tSNETable.ColumnEditable = [false, true];
            
            app.updateImages();
        end
        
        function updateImages(app)
            inputSize = app.Net.Layers(1).InputSize(1:2);
            data = app.DataModel.getRandomData(inputSize);
            imshow(imtile(data), 'Parent', app.DataRandomImagesUIAxes);
        end
        
    end
    
    methods (Access = private)
        
        function doImagePredict(app, img, trueLabel)
            inputSize = app.Net.Layers(1).InputSize(1:2);
            img = imresize(img, inputSize);
            app.CurrentPredictImage = img;
            
            % Update random image across tabs
            imshow(img, "Parent", app.ExplainerSingleImageUIAxes);
            imshow(img, "Parent", app.ActivationsSingleImageUIAxes);
            imshow(img, "Parent", app.ActivationDistributionSingleImageUIAxes);
            imshow(img, "Parent", app.PredictSingleImageUIAxes);
            app.PredictImageValue.Text = string(trueLabel);
            app.ExplainerValue.Text = string(trueLabel);
            
            % Predict
            [~, scores] = app.Net.classify(img);
            [s, idx] = sort(scores, 'descend');
            predLabels = app.Net.Layers(end).Classes(idx);
            
            % Bar chart of top 5 class scores
            numClassesToShow = 1:5;
            bar(app.PredictHistUIAxes, s(numClassesToShow));
            
            classNamesTop = string(predLabels(numClassesToShow));
            classNamesTop  = strrep(classNamesTop, "_", " ");
            app.PredictHistUIAxes.XTickLabel = classNamesTop;
            app.PredictHistUIAxes.XTickLabelRotation = 60;
            
            % Update table
            tableData(:,1) = cellstr(predLabels);
            tableData(:,2) = cellstr(compose("%.3f", s))';
            
            correctIdx = predLabels == trueLabel;
            tableData(correctIdx,1) = cellstr(string(trueLabel) + ' (true class)');

            
            app.PredictScoreUITable.Data = tableData;
            
            % Update attributions dropdown
            app.OcclusionTargetclassDropDown.Value = char(predLabels(1));
            app.GradCAMTargetclassDropDown.Value = char(predLabels(1));
            app.GradientAttributionTargetclassDropDown.Value = char(predLabels(1));
            
            if  ismember(trueLabel, app.DataModel.Classes)
                app.PredictRandomImageClassDropDown.Value = char(trueLabel);
                app.ExplainerRandomImageClassDropDown.Value = char(trueLabel);
                app.ActivationsRandomImageClassDropDown.Value = char(trueLabel);
                app.ActivationDistributionRandomImageClassDropDown.Value = char(trueLabel);
            end
        end
        
        function createDLNet(app)
            net = app.Net;
            lgraph = layerGraph(net);
            lgraph = removeLayers(lgraph, lgraph.Layers(end).Name);
            app.DLNet = dlnetwork(lgraph);
        end
        
        function [predLabels, scores] = computeOrRetrieveClassificationResults(app)
            % Retrieve classification predictions and scores (faster than
            % computing from scratch)
            if ~isempty(app.ClassificationCache)
                predLabels = app.ClassificationCache.PredLabels;
                scores = app.ClassificationCache.Scores;
                
                % Compute classification predictions and scores
            else
                app.ClassificationCache = struct();
                
                % Resize images to match network input size
                augimds = augmentedImageDatastore(app.Net.Layers(1).InputSize(1:2), ...
                    app.Data);
                
                % Classify images and store results
                [predLabels, scores] = app.Net.classify(augimds);
                app.ClassificationCache.PredLabels = predLabels;
                app.ClassificationCache.TrueLabels = app.Data.Labels;
                app.ClassificationCache.Scores = scores;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, trainedNetwork, imds)
            NetClass = {'SeriesNetwork','DAGNetwork'};
            DataClass = {'matlab.io.datastore.ImageDatastore'};
            attrs = {'scalar', 'nonempty'};

            try
                assert( nargin==3, ...
                    "To use UNPIC, pass a trained network and a image " + ...
                    "datastore with test images, e.g., " + newline + ...
                    ">> UNPIC(network, imds)");
                validateattributes(trainedNetwork,NetClass,attrs)
                validateattributes(imds,DataClass,attrs)
            catch err
                delete(app)
                rethrow(err)
            end

            appUpdate(app, trainedNetwork, imds);            
            
        end

        % Cell selection callback: DataClassTable
        function DataClassTableCellSelection(app, event)
            indices = event.Indices;
            
            selectedClasses = app.DataClassTable.Data(indices(:,1), 1);
            inputSize = app.Net.Layers(1).InputSize(1:2);
            
            % Get random images from specified class
            data = app.DataModel.getRandomDataByLabel(selectedClasses, inputSize);
            
            imshow(imtile(data), 'Parent', app.DataRandomImagesUIAxes);
        end

        % Button pushed function: DataRandomImagesButton
        function DataRandomImagesButtonPushed(app, event)
            app.updateImages();
        end

        % Value changed function: DataNumObsToShowSpinner
        function DataNumObsToShowSpinnerValueChanged(app, event)
            value = app.DataNumObsToShowSpinner.Value;
            app.DataModel.NumObsToShow = value;
            app.updateImages();
        end

        % Button pushed function: 
        % ActivationDistributionSingleRandomImageButton, 
        % ActivationsSingleRandomImageButton, 
        % ExplainerSingleRandomImageButton, 
        % PredictSingleRandomImageButton
        function RandomImageButtonPushed(app, event)
            [data, trueLabels] = app.DataModel.getRandomData();
            
            app.doImagePredict(data{1}, trueLabels(1));
        end

        % Value changed function: PredictRandomImageClassDropDown
        function PredictRandomImageClassDropDownValueChanged(app, event)
            value = cellstr(app.PredictRandomImageClassDropDown.Value);
            
            idxClass = find(app.Data.Labels == value);
            idxi = randi(length(idxClass));
            idxRand = idxClass(idxi);
            img = readimage(app.Data,idxRand);
            app.doImagePredict(img, app.Data.Labels(idxRand));
        end

        % Button pushed function: AccuracyButton
        function AccuracyButtonPushed(app, event)
             d = uiprogressdlg(app.UNPICUIFigure, ...
                'Indeterminate','on', ...
                'Title','Please Wait',...
                'Message','Generating predictions...');
            drawnow
            
            predLabels = app.computeOrRetrieveClassificationResults();
            isCorrect = (predLabels == app.Data.Labels);
            accuracy = sum(isCorrect)/length(predLabels);
            
            app.AccuracyLabel.Text = sprintf("Accuracy: %.2f %%", 100*accuracy);
            
            for i=1:length(app.DataModel.Classes)
                isOfClass = (app.Data.Labels == app.DataModel.Classes(i));
                numObs = sum(isOfClass);
                
                % Compute number of observations of class i correctly
                % predicted as class i
                isCorrectOfClass = isOfClass & isCorrect;
                
                app.DataModel.Classes(i);
                correctFraction(i) = sum(isCorrectOfClass)/numObs;
            end
            
            [sortedCorrectFraction, idx] = sort(correctFraction, 'descend');
            
            tableData(:,1) = cellstr(app.DataModel.Classes(idx));
            tableData(:,2) = cellstr(compose("%.3f", sortedCorrectFraction));
            
            app.AccuracyTable.Data = tableData;
            
            close(d)
        end

        % Value changed function: PredictChooseImageFileEditField
        function PredictChooseImageFileEditFieldValueChanged(app, event)
            value = string(app.PredictChooseImageFileEditField.Value);
            img = imread(value);
            
            % The image loaded from file doesn't have a class label attached
            app.doImagePredict(img, "(unknown)");
            
        end

        % Cell selection callback: AccuracyTable
        function AccuracyTableCellSelection(app, event)
            indices = event.Indices;
            
            selectedClasses = app.AccuracyTable.Data(indices(:,1), 1);
            predClass = selectedClasses;
            
            % Images predicted as selected class
            predLabels = app.computeOrRetrieveClassificationResults();
            
            idx = 1:app.DataModel.TotalObservations;
            idx = idx(predClass == predLabels);
            
            if ~isempty(idx)
                % Randomly sample 9 images from the predicted class
                if length(idx) < 9
                    idxi = randi(length(idx),9,1);
                    idxClassRand = idx(idxi);
                else
                    idxi = randperm(length(idx),9);
                    idxClassRand = idx(idxi);
                end
                
                for i=1:9
                    imgi = readimage(app.Data,idxClassRand(i));
                    sz = app.Net.Layers(1).InputSize(1:2);
                    img(:,:,:,i) = imresize(imgi, sz);
                end
                
                imshow(imtile(img), "Parent", app.PredictedClassImagesUIAxes);
                I = "Predicted class: " + string(predClass);
                
            else
                img = ones(128,128,3);
                imshow(img, "Parent", app.PredictedClassImagesUIAxes)
                I = "No images predicted as class " + string(predClass);
                
            end
            app.PredictedClassImagesValue.Text = I;
            
        end

        % Callback function
        function ConfusionforDropDownValueChanged(app, event)
            app.computeAndPlotConfusion();

        end

        % Button pushed function: ConfusionMatrixButton
        function ConfusionMatrixButtonPushed(app, event)
            try
                confusionchart(app.ConfusionMatrixPanel, ...
                    app.ClassificationCache.TrueLabels, ...
                    app.ClassificationCache.PredLabels, ...
                    "Normalization","row-normalized");
            catch
                uialert(app.UNPICUIFigure, ...
                    "Unable to compute confusion matrix." + newline + newline + ...
                    "Compute the network accuracy before computing the confusion matrix.", ...
                    'Error')
            end
            
        end

        % Button pushed function: TruevsPredRandomImageButton
        function TruevsPredRandomImageButtonPushed(app, event)
            trueDrop = app.TruevsPredTrueClassDropDown.Value;
            predDrop = app.TruevsPredPredictedClassDropDown.Value;
            
            predLabels = app.computeOrRetrieveClassificationResults();
            trueLabels = app.Data.Labels;
            
            idx = 1:app.DataModel.TotalObservations;
            % Index of those matching the true and predicted dropdown
            idxSelectTruePredict = idx(predDrop == predLabels & trueDrop == trueLabels);
            
            if ~isempty(idxSelectTruePredict)
                idxi = randi(length(idxSelectTruePredict));
                idxClassRand = idxSelectTruePredict(idxi);
                
                img = readimage(app.Data,idxClassRand);
                imshow(img, "Parent", app.TruevsPredUIAxes);
                I = string(trueDrop) + " classified as " + string(predDrop);
            else
                img = ones(128,128,3);
                imshow(img, "Parent", app.TruevsPredUIAxes)
                I = "No image of class " + string(trueDrop) + ...
                    " classified as " + string(predDrop);
                
            end
            app.TruevsPredValue.Text = I;
            
        end

        % Callback function
        function TrueClassDropDownValueChanged(app, event)
            value = app.TruevsPredTrueClassDropDown.Value;

        end

        % Value changed function: ExplainerChooseImageFileEditField
        function ExplainerChooseImageFileEditFieldValueChanged(app, event)
            value = string(app.ExplainerChooseImageFileEditField.Value);
            img = imread(value);
            app.doImagePredict(img, "(unknown)");
        end

        % Value changed function: ExplainerRandomImageClassDropDown
        function ExplainerRandomImageClassDropDownValueChanged(app, event)
            value = cellstr(app.ExplainerRandomImageClassDropDown.Value);
            
            idxClass = find(app.Data.Labels == value);
            idxi = randi(length(idxClass));
            idxRand = idxClass(idxi);
            img = readimage(app.Data,idxRand);
            app.doImagePredict(img, app.Data.Labels(idxRand));
        end

        % Button pushed function: OcclusionButton
        function OcclusionButtonPushed(app, event)
            % Class label used to calculate change in classification score
            targetLabel = categorical(cellstr(app.OcclusionTargetclassDropDown.Value));
            
            try
                map = occlusionSensitivity(app.Net, app.CurrentPredictImage, ...
                    targetLabel, ...
                    "MaskSize", app.OcclusionMasksizeSpinner.Value, ...
                    "Stride", app.OcclusionStrideSpinner.Value);
            catch
                uialert(app.UNPICUIFigure, ...
                    "Unable to compute occlusion sensitivity." + newline + newline + ...
                    "Select an image and valid mask size before computing the occlusion sensitivity map.", ...
                    'Error')
                return
            end
            
            imshow(app.CurrentPredictImage, "Parent", app.ExplainerSingleImageUIAxes);
            
            % Overlap occlusion mapping over current image
            app.ExplainerSingleImageUIAxes.NextPlot = "add";
            imagesc(map, "Parent", app.ExplainerSingleImageUIAxes, ...
                "AlphaData", 0.5);
            
            % 'jet' colormap has deep blue as the lowest value and deep red
            % as the highest.
            app.ExplainerSingleImageUIAxes.Colormap = jet();
            app.ExplainerSingleImageUIAxes.NextPlot = "replace";
        end

        % Value changed function: OcclusionSlider
        function OcclusionSliderValueChanged(app, event)
            value = app.OcclusionSlider.Value;
            
            % Sensible defaults for most MATLAB pretrained networks with
            % input size approx. 227-by-227
            app.OcclusionMasksizeSpinner.Value = 2 + (5-value) * 40;
            app.OcclusionStrideSpinner.Value = 1 + (5-value) * 15;
        end

        % Button pushed function: GradCAMButton
        function GradCAMButtonPushed(app, event)
            
            % Create a dlnetwork
            app.createDLNet();
            
            % Find softmaxLayer
            idxSoftmaxLayer = arrayfun(@(x)isa(x, "nnet.cnn.layer.SoftmaxLayer"), ...
                app.DLNet.Layers);
            softmaxName = app.Net.Layers(idxSoftmaxLayer).Name;
            
            % Chosen feature map layer
            featureLayerName = app.GradCAMFeatureMapDropDown.Value;
            
            % Grad-CAM function shown in MATLAB documentation
            function [featureMap,dScoresdMap] = gradcam(dlnet, dlImg, softmaxName, featureLayerName, classfn)
                [scores,featureMap] = predict(dlnet, dlImg, 'Outputs', {softmaxName, featureLayerName});
                classScore = scores(classfn);
                dScoresdMap = dlgradient(classScore,featureMap);
            end
            
            try
                % To use automatic differentiation, convert image to dlarray
                dlImg = dlarray(single(app.CurrentPredictImage), "SSC");
                
                % Extract target class
                targetClass = string(app.GradCAMTargetclassDropDown.Value);
                classLabels = app.Net.Layers(end).Classes;
                chosenClass = classLabels(classLabels == targetClass);
                
                % Compute the Grad-CAM gradient for the image by calling dlfeval on the gradcam function.
                [featureMap, dScoresdMap] = dlfeval(@gradcam, app.DLNet, dlImg, ...
                    softmaxName, featureLayerName, chosenClass);
                
                % Resize the gradient map to the network image size, and
                % scale the scores to the appropriate levels for display.
                gradcamMap = sum(featureMap .* sum(dScoresdMap, [1 2]), 3);
                gradcamMap = extractdata(gradcamMap);
                gradcamMap = rescale(gradcamMap);
                gradcamMap = imresize(gradcamMap, size(dlImg, [1 2]), ...
                    'Method', 'bicubic');
                
            catch
                uialert(app.UNPICUIFigure, ...
                    "Unable to compute Grad-CAM mapping." + newline + newline + ...
                    "Select an image and a suitable layer before computing the Grad-CAM map.", ...
                    'Error')
                return
            end
            
            % Show the Grad-CAM levels on top of the image.
            imshow(app.CurrentPredictImage, "Parent", app.ExplainerSingleImageUIAxes);
            app.ExplainerSingleImageUIAxes.NextPlot = "add";
            imagesc(gradcamMap, "Parent", app.ExplainerSingleImageUIAxes, ...
                "AlphaData", 0.5);
            
            % 'jet' colormap has deep blue as the lowest value and deep red
            % as the highest.
            app.ExplainerSingleImageUIAxes.Colormap = jet();
            app.ExplainerSingleImageUIAxes.NextPlot = "replace";
        end

        % Button pushed function: GradientAttributionButton
        function GradientAttributionButtonPushed(app, event)
            % Create a dlnetwork
            app.createDLNet();
            
            % Same as function shown in documentation
            function dydI = gradientmap(dlnet, dlImg, softmaxName, classIdx)
                % Compute the gradient of a class score with respect to one
                % input image.
                scores = predict(dlnet,dlImg,'Outputs',{softmaxName});
                classScore = scores(classIdx);
                dydI = dlgradient(classScore,dlImg);
                
            end
            
            try
                % To use automatic differentiation, convert the image to a dlarray.
                dlImg = dlarray(single(app.CurrentPredictImage), "SSC");
                
                % Extract the class of interest
                classIdx = find(string(app.GradientAttributionTargetclassDropDown.Value) == ...
                    app.Net.Layers(end).Classes);
                
                % Find softmax layer
                idxSoftmaxLayer = arrayfun(@(x)isa(x, "nnet.cnn.layer.SoftmaxLayer"), app.DLNet.Layers);
                softmaxName = app.Net.Layers(idxSoftmaxLayer).Name;
                
                dydI = dlfeval(@gradientmap, app.DLNet, dlImg, softmaxName, classIdx);
                
                % Sum the absolute values of each pixel along the channel dimension,
                % then rescale between 0 and 1. Display the gradient attribution
                % map using a custom colormap with 255 colors that maps values of
                % 0 to white and 1 to black.
                map = sum(abs(extractdata(dydI)), 3);
                map = rescale(map);
                
                cmap = [linspace(1,0,255)' linspace(1,0,255)' linspace(1,0,255)'];
                
                imshow(map, "Colormap", cmap, "Parent", app.ExplainerSingleImageUIAxes);
                
            catch
                uialert(app.UNPICUIFigure, ...
                    "Unable to compute gradient attribution mapping." + newline + newline + ...
                    "Select an image before computing the gradient attribution map.", 'Error')
            end
        end

        % Value changed function: 
        % ActivationsRandomImageClassDropDown
        function ActivationsRandomImageClassDropDownValueChanged(app, event)
            value = cellstr(app.ActivationsRandomImageClassDropDown.Value);
            
            idxClass = find(app.Data.Labels == value);
            idxi = randi(length(idxClass));
            idxRand = idxClass(idxi);
            img = readimage(app.Data,idxRand);
            app.doImagePredict(img, app.Data.Labels(idxRand));
            
        end

        % Value changed function: 
        % ActivationsChooseImageFileEditField
        function ActivationsChooseImageFileEditFieldValueChanged(app, event)
            value = string(app.ActivationsChooseImageFileEditField.Value);
            img = imread(value);
            
            % The image loaded from file doesn't have a class label attached
            app.doImagePredict(img, "(unknown)");
            
        end

        % Button pushed function: ActivationsButton
        function ActivationsButtonPushed(app, event)
            activationsLayer = app.FeaturesChooseLayerDropDown.Value;
            img = app.CurrentPredictImage;
            
            try
                acts = app.Net.activations(img,activationsLayer);
            catch
                uialert(app.UNPICUIFigure, ...
                    "Unable to compute activations." + newline + newline + ...
                    "Select a suitable layer, channels, and image before computing the" + ...
                    " activations.", 'Error')
                return
            end
            
            channels = str2num(app.FeaturesChooseChannelsEditField.Value);
            
            actsToShow = acts(:,:,channels);
            
            imshow(imtile(uint8(255*rescale(actsToShow))), "Parent", app.ActivationsUIAxes);
            app.ActivationsUIAxes.Colormap = jet();
        end

        % Button pushed function: 
        % ActivationDistributionComputeButton
        function ActivationDistributionComputeButtonPushed(app, event)
            activationsLayer = app.FeaturesChooseLayerDropDown.Value;
            img = app.CurrentPredictImage;
            
            try
                acts = app.Net.activations(img,activationsLayer);
            catch
                uialert(app.UNPICUIFigure, ...
                    "Unable to compute activation distributions." + newline + newline + ...
                    "Select a suitable layer, channels, and image before computing the" + ...
                    " activation distributions.",  'Error')
                return
            end
            
            % Extract channels of interest
            channels = str2num(app.FeaturesChooseChannelsEditField.Value);
            actsToShow = acts(:,:,channels);
            
            numSubplots = length(channels);
            subplotsPerDim = ceil(sqrt(numSubplots));
            
            app.ActivationDistributionPanel.AutoResizeChildren = 'off';
            for i=1:numSubplots
                ax = subplot(subplotsPerDim, subplotsPerDim, i, ...
                    'Parent',app.ActivationDistributionPanel);
                
                % Plot histogram of the activations
                histogram(ax, actsToShow(:,:,i));
            end
        end

        % Value changed function: 
        % ActivationDistributionChooseImageFileEditField
        function ActivationDistributionChooseImageFileEditFieldValueChanged(app, event)
            value = string(app.ActivationDistributionChooseImageFileEditField.Value);
            img = imread(value);
            
            % The image loaded from file doesn't have a class label attached
            app.doImagePredict(img, "(unknown)");
        end

        % Value changed function: 
        % ActivationDistributionRandomImageClassDropDown
        function ActivationDistributionRandomImageClassDropDownValueChanged(app, event)
            value = cellstr(app.ActivationDistributionRandomImageClassDropDown.Value);
            
            idxClass = find(app.Data.Labels == value);
            idxi = randi(length(idxClass));
            idxRand = idxClass(idxi);
            
            img = readimage(app.Data,idxRand);
            app.doImagePredict(img, app.Data.Labels(idxRand));
        end

        % Button pushed function: MaxActivationsImagesButton
        function MaxActivationsImagesButtonPushed(app, event)
            try
                % Extract layer of interest
                layerName = app.FeaturesChooseLayerDropDown.Value;
                
                augimds = augmentedImageDatastore(app.Net.Layers(1).InputSize(1:2), ...
                    app.Data);
                
                % Compute the activations of the chosen layer
                acts = app.Net.activations(augimds, layerName);
                
                % Pick out target channels
                channels = str2num(app.FeaturesChooseChannelsEditField.Value);
                actsToShow = acts(:,:,channels,:);
                
                % Sum over spatial dims
                reducedActs = sum(actsToShow, [1 2]);
            catch
                uialert(app.UNPICUIFigure, ...
                    "Unable to compute max activations." + newline + newline + ...
                    "Select a suitable layer and channels before computing the" + ...
                    " max activations.",  'Error')
                return
            end
            
            % Find max activating images for each channel chosen
            numSubplots = length(channels);
            subplotsPerDim = ceil(sqrt(numSubplots));
            
            % How many high activating images to show per channel
            imgsToShow = str2num(app.MaxActivationsImagesperchannelEditField.Value);
            
            app.MaxActivationsPanel.AutoResizeChildren = 'off';
            for i=1:numSubplots
                % Remove dimensions of length 1
                scores = squeeze(reducedActs(:,:,i,:));
                
                % Sort the scores from high to low
                [~, idx] = sort(scores, 'descend');
                
                ax = subplot(subplotsPerDim, subplotsPerDim, i, ...
                    'Parent',app.MaxActivationsPanel);
                dsSubset = app.Data.subset(idx(1:imgsToShow));
                result = dsSubset.readall();
                imshow(imtile(result), "Parent", ax);
            end
        end

        % Button pushed function: DeepDreamComputeButton
        function DeepDreamComputeButtonPushed(app, event)
             d = uiprogressdlg(app.UNPICUIFigure, ...
                'Indeterminate','on', ...
                'Title','Please Wait',...
                'Message','Generating deep dream images...');
            drawnow
            
            try
                channels = str2num(app.FeaturesChooseChannelsEditField.Value);
                layerName = app.FeaturesChooseLayerDropDown.Value;
                
                % deepDream
                I = deepDreamImage(app.Net, layerName, channels, ...
                    "NumIterations", app.DeepDreamIterationsSpinner.Value, ...
                    "PyramidLevels", app.DeepDreamPyrLvlsSpinner.Value, ...
                    "Verbose", app.DeepDreamVerboseCheckBox.Value);
                
                imshow(imtile(I), "Parent", app.DeepDreamUIAxes);
                close(d)
                
            catch
                close(d)
                uialert(app.UNPICUIFigure, ...
                    "Unable to compute deep dream." + newline + newline + ...
                    "Select a suitable layer and channels before dreaming.", ...
                    'Error')
            end
        end

        % Button pushed function: tSNEUpdateTableButton
        function tSNEUpdateTableButtonPushed(app, event)
            data = app.tSNETable.Data;
            data(:,2) = num2cell(false);
            data(1:app.tSNEIncludeEverySpinner.Value:end, 2) = num2cell(true);
            app.tSNETable.Data = data;
        end

        % Button pushed function: tSNEButton
        function tSNEButtonPushed(app, event)
            d = uiprogressdlg(app.UNPICUIFigure, ...
                'Indeterminate','on', ...
                'Title','Please Wait',...
                'Message','Generating tsne plot...');
            drawnow
            
            % Which classes to include in tsne computation
            isIncludedInCompute = cell2mat(app.tSNETable.Data(:,2));
            classesInCompute = app.tSNETable.Data(isIncludedInCompute, 1);
            isInCompute = ismember(app.Data.Labels, classesInCompute);
            
            g = app.Data.Labels(isInCompute);
            
            imds = app.Data.subset(isInCompute);
            augimds = augmentedImageDatastore(app.Net.Layers(1).InputSize(1:2), ...
                imds);
            
            % Activations of specified layer
            acts = app.Net.activations(augimds, app.tSNEChooseLayerDropDown.Value, ...
                "OutputAs", "rows");
            
            reduced = tsne(acts);
            colors = hsv(length(app.DataModel.Classes));
            h = gscatter(app.tSNEUIAxes, reduced(:,1), reduced(:,2), g, ...
                colors, [], 10);
            
            app.TSNEPlotter = TSNEPlotter(app.tSNEUIAxes, h, reduced, imds, ...
                app.computeOrRetrieveClassificationResults());
            
            % Click an image in the tsne plot to view it
            app.TSNEPlotter.setUpOnClickBehavior();
            
            close(d)
        end

        % Button pushed function: OcclusionHelpButton
        function OcclusionHelpButtonPushed(app, event)
            web(['https://www.mathworks.com/help/deeplearning/ug/understand' ...
                '-network-predictions-using-occlusion.html']);
        end

        % Button pushed function: GradCAMHelpButton
        function GradCAMHelpButtonPushed(app, event)
            web(['https://www.mathworks.com/help/deeplearning/ug/' ...
                'gradcam-explains-why.html']);
        end

        % Button pushed function: GradientAttributionHelpButton
        function GradientAttributionHelpButtonPushed(app, event)
            web(['https://www.mathworks.com/help/deeplearning/ug/investigate-' ...
                'classification-decisions-using-gradient-attribution-techniques.html']);
        end

        % Button pushed function: ActivationsHelpButton
        function ActivationsHelpButtonPushed(app, event)
            web(['https://www.mathworks.com/help/deeplearning/ug/visualize-' ...
                'activations-of-a-convolutional-neural-network.html']);
        end

        % Button pushed function: MaxActivationsHelpButton
        function MaxActivationsHelpButtonPushed(app, event)
            web(['https://www.mathworks.com/help/deeplearning/ug/visualize-' ...
                'image-classifications-using-maximal-and-minimal-activating-images.html']);
        end

        % Button pushed function: DeepDreamHelpButton
        function DeepDreamHelpButtonPushed(app, event)
            web(['https://www.mathworks.com/help/deeplearning/ug/deep-dream' ...
                '-images-using-googlenet.html']);
        end

        % Button pushed function: tSNEHelpButton
        function tSNEHelpButtonPushed(app, event)
            web(['https://www.mathworks.com/help/deeplearning/ug/view-' ...
                'network-behavior-using-tsne.html']);
        end

        % Close request function: UNPICUIFigure
        function UNPICUIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UNPICUIFigure = uifigure('Visible', 'off');
            app.UNPICUIFigure.Name = 'Understanding Network Predictions for Image Classification (UNPIC)';
            app.UNPICUIFigure.CloseRequestFcn = createCallbackFcn(app, @UNPICUIFigureCloseRequest, true);
            app.MainPanel = uipanel(app.UNPICUIFigure);
            app.MainTabGroup = uitabgroup(app.MainPanel);
            app.ImageDataTab = uitab(app.MainTabGroup);
            app.ImageDataTab.Title = 'Image Data';
            app.DataRandomImagesUIAxes = uiaxes(app.ImageDataTab);
            title(app.DataRandomImagesUIAxes, '')
            xlabel(app.DataRandomImagesUIAxes, '')
            ylabel(app.DataRandomImagesUIAxes, '')
            app.DataClassTable = uitable(app.ImageDataTab);
            app.DataClassTable.ColumnName = {'Class'; 'Number of Images'};
            app.DataClassTable.RowName = {};
            app.DataClassTable.ColumnSortable = true;
            app.DataClassTable.CellSelectionCallback = createCallbackFcn(app, @DataClassTableCellSelection, true);
            app.DataClassTable.Tooltip = {'Select a class in the table to see random images from that class'};
            app.DataRandomImagesButton = uibutton(app.ImageDataTab, 'push');
            app.DataRandomImagesButton.ButtonPushedFcn = createCallbackFcn(app, @DataRandomImagesButtonPushed, true);
            app.DataRandomImagesButton.Tooltip = {'Display random sample of observations'};
            app.DataRandomImagesButton.Text = 'Random Images';
            app.ObstoshowLabel = uilabel(app.ImageDataTab);
            app.ObstoshowLabel.Text = '# Obs. to show:';
            app.DataNumObsToShowSpinner = uispinner(app.ImageDataTab);
            app.DataNumObsToShowSpinner.Limits = [1 64];
            app.DataNumObsToShowSpinner.ValueChangedFcn = createCallbackFcn(app, @DataNumObsToShowSpinnerValueChanged, true);
            app.DataNumObsToShowSpinner.Value = 16;
            app.DataNumObsLabel = uilabel(app.ImageDataTab);
            app.DataNumObsLabel.Text = 'Number of observations:';
            app.DataNumObsValue = uilabel(app.ImageDataTab);
            app.DataNumObsValue.Text = '';
            app.DataSourceLabel = uilabel(app.ImageDataTab);
            app.DataSourceLabel.Text = 'Source:';
            app.DataSourceValue = uilabel(app.ImageDataTab);
            app.DataSourceValue.Text = '';
            app.DataNumClassesLabel = uilabel(app.ImageDataTab);
            app.DataNumClassesLabel.Text = 'Number of classes:';
            app.DataNumClassesValue = uilabel(app.ImageDataTab);
            app.DataNumClassesValue.Text = '';
            app.AccuracyTab = uitab(app.MainTabGroup);
            app.AccuracyTab.Title = 'Accuracy';
            app.AccuracyTabGroup = uitabgroup(app.AccuracyTab);
            app.ConfusionMatrixTab = uitab(app.AccuracyTabGroup);
            app.ConfusionMatrixTab.Title = 'Confusion Matrix';
            app.ConfusionMatrixPanel = uipanel(app.ConfusionMatrixTab);
            app.ConfusionMatrixPanel.Title = 'Confusion Chart';
            app.ConfusionMatrixButton = uibutton(app.ConfusionMatrixTab, 'push');
            app.ConfusionMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @ConfusionMatrixButtonPushed, true);
            app.ConfusionMatrixButton.Tooltip = {'Compute the network accuracy then click to display the confusion matrix'};
            app.ConfusionMatrixButton.Text = 'Compute Confusion Matrix';
            app.PredictedImagesbyClassTab = uitab(app.AccuracyTabGroup);
            app.PredictedImagesbyClassTab.Title = 'Predicted Images by Class';
            app.PredictedClassImagesUIAxes = uiaxes(app.PredictedImagesbyClassTab);
            title(app.PredictedClassImagesUIAxes, '')
            xlabel(app.PredictedClassImagesUIAxes, '')
            ylabel(app.PredictedClassImagesUIAxes, '')
            app.PredictedClassImagesUIAxes.XTick = [];
            app.PredictedClassImagesLabel = uilabel(app.PredictedImagesbyClassTab);
            app.PredictedClassImagesLabel.Text = 'Select a cell in the table to view images predicted to be in that class';
            app.PredictedClassImagesValue = uilabel(app.PredictedImagesbyClassTab);
            app.PredictedClassImagesValue.Text = '';
            app.TruevsPredTab = uitab(app.AccuracyTabGroup);
            app.TruevsPredTab.Title = 'True vs Predicted Class';
            app.TruevsPredUIAxes = uiaxes(app.TruevsPredTab);
            title(app.TruevsPredUIAxes, '')
            xlabel(app.TruevsPredUIAxes, '')
            ylabel(app.TruevsPredUIAxes, '')
            app.TrueclassLabel = uilabel(app.TruevsPredTab);
            app.TrueclassLabel.Text = 'True class:';
            app.TruevsPredTrueClassDropDown = uidropdown(app.TruevsPredTab);
            app.TruevsPredTrueClassDropDown.Tooltip = {''};
            app.PredictedclassLabel = uilabel(app.TruevsPredTab);
            app.PredictedclassLabel.Text = 'Predicted class:';
            app.TruevsPredPredictedClassDropDown = uidropdown(app.TruevsPredTab);
            app.TruevsPredPredictedClassDropDown.Tooltip = {''};
            app.TruevsPredRandomImageButton = uibutton(app.TruevsPredTab, 'push');
            app.TruevsPredRandomImageButton.ButtonPushedFcn = createCallbackFcn(app, @TruevsPredRandomImageButtonPushed, true);
            app.TruevsPredRandomImageButton.Text = 'Random Image';
            app.TruevsPredValue = uilabel(app.TruevsPredTab);
            app.TruevsPredValue.Text = '';
            app.AccuracyTable = uitable(app.AccuracyTab);
            app.AccuracyTable.ColumnName = {'Class'; 'Accuracy'};
            app.AccuracyTable.RowName = {};
            app.AccuracyTable.ColumnSortable = true;
            app.AccuracyTable.CellSelectionCallback = createCallbackFcn(app, @AccuracyTableCellSelection, true);
            app.AccuracyTable.Tooltip = {'Accuracy of trained network per class'};
            app.AccuracyButton = uibutton(app.AccuracyTab, 'push');
            app.AccuracyButton.ButtonPushedFcn = createCallbackFcn(app, @AccuracyButtonPushed, true);
            app.AccuracyButton.Tooltip = {'Compute the accuracy of the trained network on the imported data'};
            app.AccuracyButton.Text = 'Compute Network Accuracy';
            app.AccuracyLabel = uilabel(app.AccuracyTab);
            app.AccuracyLabel.Text = 'Total accuracy:';
            app.PredictTab = uitab(app.MainTabGroup);
            app.PredictTab.Title = 'Predict';
            app.PredictHistUIAxes = uiaxes(app.PredictTab);
            title(app.PredictHistUIAxes, 'Top 5 Class Scores')
            xlabel(app.PredictHistUIAxes, '')
            ylabel(app.PredictHistUIAxes, 'Score')
            app.PredictSingleImageUIAxes = uiaxes(app.PredictTab);
            title(app.PredictSingleImageUIAxes, '')
            xlabel(app.PredictSingleImageUIAxes, '')
            ylabel(app.PredictSingleImageUIAxes, '')
            app.PredictScoreUITable = uitable(app.PredictTab);
            app.PredictScoreUITable.ColumnName = {'Class'; 'Score'};
            app.PredictScoreUITable.RowName = {};
            app.PredictScoreUITable.ColumnSortable = true;
            app.PredictChooseImageFileEditFieldLabel = uilabel(app.PredictTab);
            app.PredictChooseImageFileEditFieldLabel.Text = 'Choose image file:';
            app.PredictChooseImageFileEditField = uieditfield(app.PredictTab, 'text');
            app.PredictChooseImageFileEditField.ValueChangedFcn = createCallbackFcn(app, @PredictChooseImageFileEditFieldValueChanged, true);
            app.PredictChooseImageFileEditField.Tooltip = {'To load an image, type the file path with extension'};
            app.PredictSingleRandomImageButton = uibutton(app.PredictTab, 'push');
            app.PredictSingleRandomImageButton.ButtonPushedFcn = createCallbackFcn(app, @RandomImageButtonPushed, true);
            app.PredictSingleRandomImageButton.Tooltip = {'Select a random image from the imported data'};
            app.PredictSingleRandomImageButton.Text = 'Random Image';
            app.PredictRandomImageClassDropDown = uidropdown(app.PredictTab);
            app.PredictRandomImageClassDropDown.ValueChangedFcn = createCallbackFcn(app, @PredictRandomImageClassDropDownValueChanged, true);
            app.PredictRandomImageClassDropDown.Tooltip = {'Select a random image from the chosen class'};
            app.RandomimagefromclassLabel_4 = uilabel(app.PredictTab);
            app.RandomimagefromclassLabel_4.Text = 'Random image from class';
            app.PredictTabTextArea = uitextarea(app.PredictTab);
            app.PredictTabTextArea.Editable = 'off';
            app.PredictTabTextArea.Value = {'Predicted scores per class for an image. Calculate for a chosen image file, or a random image from the imported data.'};
            app.PredictImageLabel = uilabel(app.PredictTab);
            app.PredictImageLabel.Text = 'True class:';
            app.PredictImageValue = uilabel(app.PredictTab);
            app.PredictImageValue.Text = '';
            app.PredictionExplainerTab = uitab(app.MainTabGroup);
            app.PredictionExplainerTab.Title = 'Prediction Explainer';
            app.ExplainerSingleImageUIAxes = uiaxes(app.PredictionExplainerTab);
            title(app.ExplainerSingleImageUIAxes, '')
            xlabel(app.ExplainerSingleImageUIAxes, '')
            ylabel(app.ExplainerSingleImageUIAxes, '')
            app.ExplainerTabGroup = uitabgroup(app.PredictionExplainerTab);
            app.OcclusionSensitivityTab = uitab(app.ExplainerTabGroup);
            app.OcclusionSensitivityTab.Title = 'Occlusion Sensitivity';
            app.OcclusionSensitivitySettingsPanel = uipanel(app.OcclusionSensitivityTab);
            app.OcclusionSensitivitySettingsPanel.Title = 'Occlusion Sensitivity Settings';
            app.OcclusionSlider = uislider(app.OcclusionSensitivitySettingsPanel);
            app.OcclusionSlider.Limits = [1 5];
            app.OcclusionSlider.MajorTicks = [1 2 3 4 5];
            app.OcclusionSlider.MajorTickLabels = {'Fast', '', '', '', 'Detailed'};
            app.OcclusionSlider.ValueChangedFcn = createCallbackFcn(app, @OcclusionSliderValueChanged, true);
            app.OcclusionSlider.MinorTicks = [];
            app.OcclusionSlider.Value = 2;
            app.MasksizeSpinnerLabel = uilabel(app.OcclusionSensitivitySettingsPanel);
            app.MasksizeSpinnerLabel.Text = 'Mask size:';
            app.OcclusionMasksizeSpinner = uispinner(app.OcclusionSensitivitySettingsPanel);
            app.OcclusionMasksizeSpinner.Limits = [1 300];
            app.OcclusionMasksizeSpinner.RoundFractionalValues = 'on';
            app.OcclusionMasksizeSpinner.Tooltip = {'Size of occluding mask. Mask size must be smaller than or equal to the image data''s size.'};
            app.OcclusionMasksizeSpinner.Value = 45;
            app.StrideLabel = uilabel(app.OcclusionSensitivitySettingsPanel);
            app.StrideLabel.Text = 'Stride:';
            app.OcclusionStrideSpinner = uispinner(app.OcclusionSensitivitySettingsPanel);
            app.OcclusionStrideSpinner.Limits = [1 300];
            app.OcclusionStrideSpinner.RoundFractionalValues = 'on';
            app.OcclusionStrideSpinner.Tooltip = {'Step size for traversing the mask across the input image'};
            app.OcclusionStrideSpinner.Value = 22;
            app.OcclusionTargetclassDropDownLabel = uilabel(app.OcclusionSensitivitySettingsPanel);
            app.OcclusionTargetclassDropDownLabel.Tooltip = {'Class to use when calculating the change in classification score'};
            app.OcclusionTargetclassDropDownLabel.Text = 'Target class:';
            app.OcclusionTargetclassDropDown = uidropdown(app.OcclusionSensitivitySettingsPanel);
            app.OcclusionTargetclassDropDown.Tooltip = {'Class to use when calculating the change in classification score'};
            app.OcclusionButton = uibutton(app.OcclusionSensitivitySettingsPanel, 'push');
            app.OcclusionButton.ButtonPushedFcn = createCallbackFcn(app, @OcclusionButtonPushed, true);
            app.OcclusionButton.Tooltip = {'Display the occlusion sensitivity mapping over the image'};
            app.OcclusionButton.Text = 'Display Occlusion Sensitivity';
            app.OcclusionTextArea = uitextarea(app.OcclusionSensitivityTab);
            app.OcclusionTextArea.Editable = 'off';
            app.OcclusionTextArea.Value = {'Occlusion sensitivity is a technique which computes a map of the change in classification score for a target class when parts of the input image are occluded with a mask. '; ''; 'Select a target class to see which parts of the image are activated for that class.'};
            app.OcclusionHelpButton = uibutton(app.OcclusionSensitivityTab, 'push');
            app.OcclusionHelpButton.ButtonPushedFcn = createCallbackFcn(app, @OcclusionHelpButtonPushed, true);
            app.OcclusionHelpButton.Text = 'Help';
            app.GradCAMTab = uitab(app.ExplainerTabGroup);
            app.GradCAMTab.Title = 'Grad-CAM';
            app.GradCAMSettingsPanel = uipanel(app.GradCAMTab);
            app.GradCAMSettingsPanel.Title = 'Grad-CAM Settings';
            app.GradCAMButton = uibutton(app.GradCAMSettingsPanel, 'push');
            app.GradCAMButton.ButtonPushedFcn = createCallbackFcn(app, @GradCAMButtonPushed, true);
            app.GradCAMButton.Tooltip = {'Display the Grad-CAM mapping over the image'};
            app.GradCAMButton.Text = 'Display Grad-CAM';
            app.GradCAMTargetclassDropDownLabel = uilabel(app.GradCAMSettingsPanel);
            app.GradCAMTargetclassDropDownLabel.Tooltip = {'Class to use when calculating the change in classification score'};
            app.GradCAMTargetclassDropDownLabel.Text = 'Target class:';
            app.GradCAMTargetclassDropDown = uidropdown(app.GradCAMSettingsPanel);
            app.GradCAMTargetclassDropDown.Tooltip = {'Class to use when calculating the gradient of the classification score with respect to the convolutional features '};
            app.GradCAMFeaturemapDropDownLabel = uilabel(app.GradCAMSettingsPanel);
            app.GradCAMFeaturemapDropDownLabel.Text = 'Feature map:';
            app.GradCAMFeatureMapDropDown = uidropdown(app.GradCAMSettingsPanel);
            app.GradCAMFeatureMapDropDown.Value = 'Option 2';
            app.GradCAMTextArea = uitextarea(app.GradCAMTab);
            app.GradCAMTextArea.Editable = 'off';
            app.GradCAMTextArea.Value = {'Grad-CAM uses the gradient of the classification score with respect to the convolutional features determined by the network to understand which parts of the image are most important for classification. '; ''; 'Select a target class to see which parts of the image are most important for that class.'};
            app.GradCAMHelpButton = uibutton(app.GradCAMTab, 'push');
            app.GradCAMHelpButton.ButtonPushedFcn = createCallbackFcn(app, @GradCAMHelpButtonPushed, true);
            app.GradCAMHelpButton.Text = 'Help';
            app.GradientAttributionTab = uitab(app.ExplainerTabGroup);
            app.GradientAttributionTab.Title = 'Gradient Attribution';
            app.GradientAttributionSettingsPanel = uipanel(app.GradientAttributionTab);
            app.GradientAttributionSettingsPanel.Title = 'Gradient Attribution Settings';
            app.GradientAttributionTargetclassDropDownLabel = uilabel(app.GradientAttributionSettingsPanel);
            app.GradientAttributionTargetclassDropDownLabel.Text = 'Target class:';
            app.GradientAttributionTargetclassDropDown = uidropdown(app.GradientAttributionSettingsPanel);
            app.GradientAttributionButton = uibutton(app.GradientAttributionSettingsPanel, 'push');
            app.GradientAttributionButton.ButtonPushedFcn = createCallbackFcn(app, @GradientAttributionButtonPushed, true);
            app.GradientAttributionButton.Tooltip = {'Display the gradient attribution mapping over the image'};
            app.GradientAttributionButton.Text = 'Display Gradient Attribution';
            app.GradientAttributionTextArea = uitextarea(app.GradientAttributionTab);
            app.GradientAttributionTextArea.Editable = 'off';
            app.GradientAttributionTextArea.Value = {'The gradient attribution method provides pixel-resolution maps that show which pixels are most important to the network''s classification.'; ''; 'Select a target class to see which parts of the image are most important for that class.'};
            app.GradientAttributionHelpButton = uibutton(app.GradientAttributionTab, 'push');
            app.GradientAttributionHelpButton.ButtonPushedFcn = createCallbackFcn(app, @GradientAttributionHelpButtonPushed, true);
            app.GradientAttributionHelpButton.Text = 'Help';
            app.ExplainerChooseImageFileEditFieldLabel = uilabel(app.PredictionExplainerTab);
            app.ExplainerChooseImageFileEditFieldLabel.Text = 'Choose image file:';
            app.ExplainerChooseImageFileEditField = uieditfield(app.PredictionExplainerTab, 'text');
            app.ExplainerChooseImageFileEditField.ValueChangedFcn = createCallbackFcn(app, @ExplainerChooseImageFileEditFieldValueChanged, true);
            app.ExplainerChooseImageFileEditField.Tooltip = {'To load an image, type the file path with extension'};
            app.ExplainerSingleRandomImageButton = uibutton(app.PredictionExplainerTab, 'push');
            app.ExplainerSingleRandomImageButton.ButtonPushedFcn = createCallbackFcn(app, @RandomImageButtonPushed, true);
            app.ExplainerSingleRandomImageButton.Tooltip = {'Select a random image from the imported data'};
            app.ExplainerSingleRandomImageButton.Text = 'Random Image';
            app.RandomimagefromclassLabel_3 = uilabel(app.PredictionExplainerTab);
            app.RandomimagefromclassLabel_3.Text = 'Random image from class';
            app.ExplainerRandomImageClassDropDown = uidropdown(app.PredictionExplainerTab);
            app.ExplainerRandomImageClassDropDown.ValueChangedFcn = createCallbackFcn(app, @ExplainerRandomImageClassDropDownValueChanged, true);
            app.ExplainerRandomImageClassDropDown.Tooltip = {'Select a random image from the chosen class'};
            app.ExplainerTabTextArea = uitextarea(app.PredictionExplainerTab);
            app.ExplainerTabTextArea.Value = {'Visualize which parts of an image are most important for classification. Visualize for a chosen image file, or a random image from the test data. '};
            app.ExplainerLabel = uilabel(app.PredictionExplainerTab);
            app.ExplainerLabel.Text = 'True class:';
            app.ExplainerValue = uilabel(app.PredictionExplainerTab);
            app.ExplainerValue.Text = '';
            app.FeaturesTab = uitab(app.MainTabGroup);
            app.FeaturesTab.Title = 'Features';
            app.FeaturesSettingsPanel = uipanel(app.FeaturesTab);
            app.FeaturesSettingsPanel.Title = 'Layer and channels of interest';
            app.ChannelVizChooseLayerDropDownLabel = uilabel(app.FeaturesSettingsPanel);
            app.ChannelVizChooseLayerDropDownLabel.Text = 'Layer:';
            app.FeaturesChooseLayerDropDown = uidropdown(app.FeaturesSettingsPanel);
            app.FeaturesChooseLayerDropDown.Tooltip = {'Select the layer to visualize'};
            app.FeaturesChooseLayerDropDown.Value = 'Option 2';
            app.ChannelVizChooseChannelsEditFieldLabel = uilabel(app.FeaturesSettingsPanel);
            app.ChannelVizChooseChannelsEditFieldLabel.Text = 'Channels:';
            app.FeaturesChooseChannelsEditField = uieditfield(app.FeaturesSettingsPanel, 'text');
            app.FeaturesChooseChannelsEditField.Tooltip = {'Select the channels to inspect'};
            app.FeaturesChooseChannelsEditField.Value = '1:4';
            app.FeaturesTabGroup = uitabgroup(app.FeaturesTab);
            app.ActivationsTab = uitab(app.FeaturesTabGroup);
            app.ActivationsTab.Title = 'Activations';
            app.ActivationsUIAxes = uiaxes(app.ActivationsTab);
            title(app.ActivationsUIAxes, '')
            xlabel(app.ActivationsUIAxes, '')
            ylabel(app.ActivationsUIAxes, '')
            app.ActivationsSingleImageUIAxes = uiaxes(app.ActivationsTab);
            title(app.ActivationsSingleImageUIAxes, '')
            xlabel(app.ActivationsSingleImageUIAxes, '')
            ylabel(app.ActivationsSingleImageUIAxes, '')
            app.ActivationsButton = uibutton(app.ActivationsTab, 'push');
            app.ActivationsButton.ButtonPushedFcn = createCallbackFcn(app, @ActivationsButtonPushed, true);
            app.ActivationsButton.Tooltip = {'Select image, layers, and channels, then click to compute the activations. This can take some time.'};
            app.ActivationsButton.Text = 'Compute Activations';
            app.ActivationsTextArea = uitextarea(app.ActivationsTab);
            app.ActivationsTextArea.Editable = 'off';
            app.ActivationsTextArea.Value = {'Investigate features the network has learned by observing which areas in the layers activate on an image.'};
            app.ActivationsChooseImageFileEditFieldLabel = uilabel(app.ActivationsTab);
            app.ActivationsChooseImageFileEditFieldLabel.Text = 'Choose image file:';
            app.ActivationsChooseImageFileEditField = uieditfield(app.ActivationsTab, 'text');
            app.ActivationsChooseImageFileEditField.ValueChangedFcn = createCallbackFcn(app, @ActivationsChooseImageFileEditFieldValueChanged, true);
            app.ActivationsChooseImageFileEditField.Tooltip = {'To load an image, type the file path with extension'};
            app.ActivationsSingleRandomImageButton = uibutton(app.ActivationsTab, 'push');
            app.ActivationsSingleRandomImageButton.ButtonPushedFcn = createCallbackFcn(app, @RandomImageButtonPushed, true);
            app.ActivationsSingleRandomImageButton.Tooltip = {'Select a random image from the imported data'};
            app.ActivationsSingleRandomImageButton.Text = 'Random Image';
            app.ActivationsRandomImageClassDropDown = uidropdown(app.ActivationsTab);
            app.ActivationsRandomImageClassDropDown.ValueChangedFcn = createCallbackFcn(app, @ActivationsRandomImageClassDropDownValueChanged, true);
            app.ActivationsRandomImageClassDropDown.Tooltip = {'Select a random image from the chosen class'};
            app.RandomimagefromclassLabel_2 = uilabel(app.ActivationsTab);
            app.RandomimagefromclassLabel_2.Text = 'Random image from class';
            app.ActivationsHelpButton = uibutton(app.ActivationsTab, 'push');
            app.ActivationsHelpButton.ButtonPushedFcn = createCallbackFcn(app, @ActivationsHelpButtonPushed, true);
            app.ActivationsHelpButton.Text = 'Help';
            app.ActivationDistributionTab = uitab(app.FeaturesTabGroup);
            app.ActivationDistributionTab.Title = 'Activation Distribution';
            app.ActivationDistributionSingleImageUIAxes = uiaxes(app.ActivationDistributionTab);
            title(app.ActivationDistributionSingleImageUIAxes, '')
            xlabel(app.ActivationDistributionSingleImageUIAxes, '')
            ylabel(app.ActivationDistributionSingleImageUIAxes, '')
            app.ActivationDistributionComputeButton = uibutton(app.ActivationDistributionTab, 'push');
            app.ActivationDistributionComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ActivationDistributionComputeButtonPushed, true);
            app.ActivationDistributionComputeButton.Tooltip = {'Select image, layers, and channels, then click to compute the distribution of the activations. This can take some time.'};
            app.ActivationDistributionComputeButton.Text = 'Compute Activation Distributions';
            app.ActivationDistributionPanel = uipanel(app.ActivationDistributionTab);
            app.ActivationDistributionTextArea = uitextarea(app.ActivationDistributionTab);
            app.ActivationDistributionTextArea.Editable = 'off';
            app.ActivationDistributionTextArea.Value = {'Compute the distribution of the activations in the specified channels of the chosen layer.'};
            app.ActivationDistributionChooseImageFileEditFieldLabel = uilabel(app.ActivationDistributionTab);
            app.ActivationDistributionChooseImageFileEditFieldLabel.Text = 'Choose image file:';
            app.ActivationDistributionChooseImageFileEditField = uieditfield(app.ActivationDistributionTab, 'text');
            app.ActivationDistributionChooseImageFileEditField.ValueChangedFcn = createCallbackFcn(app, @ActivationDistributionChooseImageFileEditFieldValueChanged, true);
            app.ActivationDistributionChooseImageFileEditField.Tooltip = {'To load an image, type the file path with extension'};
            app.ActivationDistributionSingleRandomImageButton = uibutton(app.ActivationDistributionTab, 'push');
            app.ActivationDistributionSingleRandomImageButton.ButtonPushedFcn = createCallbackFcn(app, @RandomImageButtonPushed, true);
            app.ActivationDistributionSingleRandomImageButton.Tooltip = {'Select a random image from the imported data'};
            app.ActivationDistributionSingleRandomImageButton.Text = 'Random Image';
            app.ActivationDistributionRandomImageClassDropDown = uidropdown(app.ActivationDistributionTab);
            app.ActivationDistributionRandomImageClassDropDown.ValueChangedFcn = createCallbackFcn(app, @ActivationDistributionRandomImageClassDropDownValueChanged, true);
            app.ActivationDistributionRandomImageClassDropDown.Tooltip = {'Select a random image from the chosen class'};
            app.RandomimagefromclassLabel = uilabel(app.ActivationDistributionTab);
            app.RandomimagefromclassLabel.Text = 'Random image from class';
            app.MaxActivationsTab = uitab(app.FeaturesTabGroup);
            app.MaxActivationsTab.Tooltip = {'Find the images which maximize the activations in the specified channels.'};
            app.MaxActivationsTab.Title = 'Max Activations';
            app.MaxActivationsPanel = uipanel(app.MaxActivationsTab);
            app.MaxActivationsTextArea = uitextarea(app.MaxActivationsTab);
            app.MaxActivationsTextArea.Editable = 'off';
            app.MaxActivationsTextArea.Value = {'Visualize the images which maximize the activations of each specified channel within the chosen layer.'};
            app.MaxActivationsImagesSettingsPanel = uipanel(app.MaxActivationsTab);
            app.MaxActivationsImagesSettingsPanel.Title = 'Max Activating Images Settings';
            app.MaxActivationsImagesButton = uibutton(app.MaxActivationsImagesSettingsPanel, 'push');
            app.MaxActivationsImagesButton.ButtonPushedFcn = createCallbackFcn(app, @MaxActivationsImagesButtonPushed, true);
            app.MaxActivationsImagesButton.Tooltip = {'Select a layer and channels then click to compute the max activation images'};
            app.MaxActivationsImagesButton.Text = 'Display Max Activating Images';
            app.MaxActsImagesperchannelEditFieldLabel = uilabel(app.MaxActivationsImagesSettingsPanel);
            app.MaxActsImagesperchannelEditFieldLabel.Text = 'Images per channel:';
            app.MaxActivationsImagesperchannelEditField = uieditfield(app.MaxActivationsImagesSettingsPanel, 'text');
            app.MaxActivationsImagesperchannelEditField.Tooltip = {'Number of max activating images to show per channel.'};
            app.MaxActivationsImagesperchannelEditField.Value = '1';
            app.MaxActivationsHelpButton = uibutton(app.MaxActivationsTab, 'push');
            app.MaxActivationsHelpButton.ButtonPushedFcn = createCallbackFcn(app, @MaxActivationsHelpButtonPushed, true);
            app.MaxActivationsHelpButton.Text = 'Help';
            app.DeepDreamTab = uitab(app.FeaturesTabGroup);
            app.DeepDreamTab.Title = 'Deep Dream';
            app.DeepDreamUIAxes = uiaxes(app.DeepDreamTab);
            title(app.DeepDreamUIAxes, '')
            xlabel(app.DeepDreamUIAxes, '')
            ylabel(app.DeepDreamUIAxes, '')
            app.DeepDreamTextArea = uitextarea(app.DeepDreamTab);
            app.DeepDreamTextArea.Editable = 'off';
            app.DeepDreamTextArea.Value = {'Deep Dream is a feature visualization technique that synthesizes images that strongly activate network layers. '};
            app.DeepDreamSettingsPanel = uipanel(app.DeepDreamTab);
            app.DeepDreamSettingsPanel.Title = 'Deep Dream Settings';
            app.DeepDreamComputeButton = uibutton(app.DeepDreamSettingsPanel, 'push');
            app.DeepDreamComputeButton.ButtonPushedFcn = createCallbackFcn(app, @DeepDreamComputeButtonPushed, true);
            app.DeepDreamComputeButton.Tooltip = {'Select a layer and channels then click to deep dream. This can take a long time.'};
            app.DeepDreamComputeButton.Text = 'Deep Dream';
            app.IterationsLabel = uilabel(app.DeepDreamSettingsPanel);
            app.IterationsLabel.Tooltip = {''};
            app.IterationsLabel.Text = 'Iterations:';
            app.DeepDreamIterationsSpinner = uispinner(app.DeepDreamSettingsPanel);
            app.DeepDreamIterationsSpinner.Limits = [1 500];
            app.DeepDreamIterationsSpinner.RoundFractionalValues = 'on';
            app.DeepDreamIterationsSpinner.Tooltip = {'Number of iterations. Increase to produce more detailed images at the expense of additional computation.'};
            app.DeepDreamIterationsSpinner.Value = 10;
            app.PyramidlevelsLabel = uilabel(app.DeepDreamSettingsPanel);
            app.PyramidlevelsLabel.Tooltip = {''};
            app.PyramidlevelsLabel.Text = 'Pyramid levels:';
            app.DeepDreamPyrLvlsSpinner = uispinner(app.DeepDreamSettingsPanel);
            app.DeepDreamPyrLvlsSpinner.Limits = [1 100];
            app.DeepDreamPyrLvlsSpinner.RoundFractionalValues = 'on';
            app.DeepDreamPyrLvlsSpinner.Tooltip = {'Pyramid levels. Increase to produce larger output images at the expense of additional computation.'};
            app.DeepDreamPyrLvlsSpinner.Value = 2;
            app.DeepDreamVerboseCheckBox = uicheckbox(app.DeepDreamSettingsPanel);
            app.DeepDreamVerboseCheckBox.Tooltip = {'Display progress information in the command window'};
            app.DeepDreamVerboseCheckBox.Text = 'Verbose';
            app.DeepDreamHelpButton = uibutton(app.DeepDreamTab, 'push');
            app.DeepDreamHelpButton.ButtonPushedFcn = createCallbackFcn(app, @DeepDreamHelpButtonPushed, true);
            app.DeepDreamHelpButton.Text = 'Help';
            app.tSNETab = uitab(app.MainTabGroup);
            app.tSNETab.Title = 't-SNE';
            app.tSNEUIAxes = uiaxes(app.tSNETab);
            title(app.tSNEUIAxes, 'T-SNE Plot with Colors Denoting True Class')
            xlabel(app.tSNEUIAxes, '')
            ylabel(app.tSNEUIAxes, '')
            app.tSNETable = uitable(app.tSNETab);
            app.tSNETable.ColumnName = {'True Class'; 'Include'};
            app.tSNETable.RowName = {};
            app.tSNETable.ColumnSortable = [true false];
            app.tSNEChooseLayerDropDownLabel = uilabel(app.tSNETab);
            app.tSNEChooseLayerDropDownLabel.Text = 'Layer:';
            app.tSNEChooseLayerDropDown = uidropdown(app.tSNETab);
            app.tSNEChooseLayerDropDown.Value = 'Option 2';
            app.tSNEIncludeeverySpinnerLabel = uilabel(app.tSNETab);
            app.tSNEIncludeeverySpinnerLabel.Text = 'Include every';
            app.tSNEIncludeEverySpinner = uispinner(app.tSNETab);
            app.tSNEIncludeEverySpinner.Limits = [1 100];
            app.tSNEIncludeEverySpinner.RoundFractionalValues = 'on';
            app.tSNEIncludeEverySpinner.Tooltip = {'Include every x classes.'};
            app.tSNEIncludeEverySpinner.Value = 5;
            app.tSNEButton = uibutton(app.tSNETab, 'push');
            app.tSNEButton.ButtonPushedFcn = createCallbackFcn(app, @tSNEButtonPushed, true);
            app.tSNEButton.Tooltip = {'Compute tsne (this can take a long time)'};
            app.tSNEButton.Text = {'Compute t-SNE'; ''};
            app.tSNEUpdateTableButton = uibutton(app.tSNETab, 'push');
            app.tSNEUpdateTableButton.ButtonPushedFcn = createCallbackFcn(app, @tSNEUpdateTableButtonPushed, true);
            app.tSNEUpdateTableButton.Tooltip = {'Update which classes in the table to compute and plot'};
            app.tSNEUpdateTableButton.Text = 'Update Table';
            app.tSNETextArea = uitextarea(app.tSNETab);
            app.tSNETextArea.Editable = 'off';
            app.tSNETextArea.Value = {'Use t-SNE to visualize how deep learning networks change the representation of input data as it passes through the network layers. This method tries to preserve distances so that points near each other in the high-dimensional representation are also near each other in the low-dimensional representation.'};
            app.tSNEHelpButton = uibutton(app.tSNETab, 'push');
            app.tSNEHelpButton.ButtonPushedFcn = createCallbackFcn(app, @tSNEHelpButtonPushed, true);
            app.tSNEHelpButton.Text = 'Help';
            app.tSNETipLabel = uilabel(app.tSNETab);
            app.tSNETipLabel.Text = 'Tip: Click a point on the tsne plot to view the image.';
            % Show the figure after all components are created
            app.UNPICUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UNPIC(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UNPICUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UNPICUIFigure)
        end
    end
end